classdef CIEExplorer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                       matlab.ui.Figure
		GridLayout                     matlab.ui.container.GridLayout
		GridLayout2                    matlab.ui.container.GridLayout
		CIEdatasetButtonGroup_2        matlab.ui.container.ButtonGroup
		cie_1964_10_degree_observer_RButton  matlab.ui.control.RadioButton
		cie_1931_2_degree_observer_RButton  matlab.ui.control.RadioButton
		cie_1931_1nm_observer_RButton  matlab.ui.control.RadioButton
		PlotButton                     matlab.ui.control.Button
		VisualizationButtonGroup       matlab.ui.container.ButtonGroup
		ThreeDButton                   matlab.ui.control.RadioButton
		LineChartButton                matlab.ui.control.RadioButton
		ExitButton                     matlab.ui.control.Button
		UIAxes                         matlab.ui.control.UIAxes
	end


	properties (Access = private)
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)

		end

		% Button pushed function: ExitButton
		function ExitButtonPushed(app, event)
			app.delete();
		end

		% Button pushed function: PlotButton
		function PlotButtonPushed(app, event)
			% need determine what selections the user has made
			if app.cie_1964_10_degree_observer_RButton.Value == true
				dataset = readtable("etc\CIE_cc_1964_10deg.csv");
				nm = dataset{:,1};
				r = dataset{:,2};
				g = dataset{:,3};
				b = dataset{:,4};
				plot(app.UIAxes, nm, r, 'r');
				hold(app.UIAxes, "on");
				plot(app.UIAxes, nm, g, 'g');
				plot(app.UIAxes, nm, b, 'b');
				hold(app.UIAxes, "off");
				% Add labels and title
				xlabel(app.UIAxes, 'Nanometers');
				ylabel(app.UIAxes, 'Values');
				title(app.UIAxes, 'xyz Values vs Nanometers');
				legend(app.UIAxes, 'x', 'y', 'z');
			end
			if app.cie_1931_2_degree_observer_RButton.Value == true
				dataset = readtable("etc\CIE_cc_1931_2deg.csv");
				nm = dataset{:,1};
				r = dataset{:,2};
				g = dataset{:,3};
				b = dataset{:,4};
				plot(app.UIAxes, nm, r, 'r');
				hold(app.UIAxes, "on");
				plot(app.UIAxes, nm, g, 'g');
				plot(app.UIAxes, nm, b, 'b');
				hold(app.UIAxes, "off");
				% Add labels and title
				xlabel(app.UIAxes, 'Nanometers');
				ylabel(app.UIAxes, 'Values');
				title(app.UIAxes, 'xyz Values vs Nanometers');
				legend(app.UIAxes, 'x', 'y', 'z');
			end
			if app.cie_1931_1nm_observer_RButton.Value == true
				dataset = readtable("etc\CIE 1931 Standard Observer (1nm).xlsx");
				nm = dataset{:,1};
				r = dataset{:,2};
				g = dataset{:,3};
				b = dataset{:,4};
				plot(app.UIAxes, nm, r, 'r');
				hold(app.UIAxes, "on");
				plot(app.UIAxes, nm, g, 'g');
				plot(app.UIAxes, nm, b, 'b');
				hold(app.UIAxes, "off");
				% Add labels and title
				xlabel(app.UIAxes, 'Nanometers');
				ylabel(app.UIAxes, 'Values');
				title(app.UIAxes, 'xyz Values vs Nanometers');
				legend(app.UIAxes, 'x', 'y', 'z');
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.UIAxes = uiaxes(app.GridLayout);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.GridLayout2 = uigridlayout(app.GridLayout);
			app.ExitButton = uibutton(app.GridLayout2, 'push');
			app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
			app.ExitButton.Text = 'Exit';
			app.VisualizationButtonGroup = uibuttongroup(app.GridLayout2);
			app.VisualizationButtonGroup.Title = 'Visualization';
			app.LineChartButton = uiradiobutton(app.VisualizationButtonGroup);
			app.LineChartButton.Text = 'Line Chart';
			app.LineChartButton.Value = true;
			app.ThreeDButton = uiradiobutton(app.VisualizationButtonGroup);
			app.ThreeDButton.Text = '3D';
			app.PlotButton = uibutton(app.GridLayout2, 'push');
			app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
			app.PlotButton.Text = 'Plot';
			app.CIEdatasetButtonGroup_2 = uibuttongroup(app.GridLayout2);
			app.CIEdatasetButtonGroup_2.Title = 'CIE data set';
			app.cie_1931_1nm_observer_RButton = uiradiobutton(app.CIEdatasetButtonGroup_2);
			app.cie_1931_1nm_observer_RButton.Text = '1931 1nm';
			app.cie_1931_1nm_observer_RButton.Value = true;
			app.cie_1931_2_degree_observer_RButton = uiradiobutton(app.CIEdatasetButtonGroup_2);
			app.cie_1931_2_degree_observer_RButton.Text = '1931 2 degree';
			app.cie_1964_10_degree_observer_RButton = uiradiobutton(app.CIEdatasetButtonGroup_2);
			app.cie_1964_10_degree_observer_RButton.Text = '1964 10 degree';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CIEExplorer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end