classdef ImagePointCloud < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DestinationProfileInfoButton    matlab.ui.control.Button
        SourceProfileInfoButton         matlab.ui.control.Button
        DestProfileLabel                matlab.ui.control.Label
        ImageProfileLabel               matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        ExitButton                      matlab.ui.control.Button
        RenderButton                    matlab.ui.control.Button
        DestProfileButton               matlab.ui.control.Button
        SourceProfileButton             matlab.ui.control.Button
        ImagefileButton                 matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        ImageView                       matlab.ui.control.Image
        GridLayout3                     matlab.ui.container.GridLayout
        ImageUniqueColorsCountLabel     matlab.ui.control.Label
        ImageColorsCountLabel           matlab.ui.control.Label
        UniquecolorsLabel               matlab.ui.control.Label
        ImagepixelsLabel                matlab.ui.control.Label
        aStarRightButton                matlab.ui.control.Button
        bStarRightButton                matlab.ui.control.Button
        astarLeftButton                 matlab.ui.control.Button
        bstarLeftButton                 matlab.ui.control.Button
        viewBottomUpButton              matlab.ui.control.Button
        viewTopDownButton               matlab.ui.control.Button
        viewOrthogonalButton            matlab.ui.control.Button
        wireframetransparencySlider     matlab.ui.control.Slider
        destinationprofileopacityLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        referenceImage % The referenece image - used to generate the Lab boundaries
        profilePath
        profile
        sourceImage
        sourceProfile
        sourceProfilePath
        sourceFilePath
        sourceFileName
        destinationProfile
        destinationProfilePath
        defaultBackground = [0.96,0.96,0.96]
        alertBackground = [0.93,0.69,0.13]
        dim = 128
        targetSaturation = 1.0
        destProfileIntensity = 0.25;    % 0.5 so it does not obscure the point cloud
        blankImage = ones(64,64,3,'uint8')*255;    % so we don't see the image placeholder
    end

    methods (Access = private)
        function keepFocus(app)
            % matlab keeps loosing focus - this is the fix.
            figure(app.UIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % clc;
            gpuDevice(1);
            app.UIFigure.Name = "Image Point Cloud";
            app.wireframetransparencySlider.Value = app.destProfileIntensity;
            app.referenceImage = generateReferenceGamut(app.dim, app.targetSaturation);
            app.ImageView.ImageSource = app.blankImage; % hide no image watermark

            tb = axtoolbar(app.UIAxes, 'default');
            tb.Visible = 'off';
            app.UIAxes.Toolbar = tb;
            app.UIAxes.Padding = 'loose'; % loose, compact or tight
            % movegui(app.UIFigure, 'center');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: ImagefileButton
        function ImagefileButtonPushed(app, event)
            % browse and open an image file
            [fn, pn] = uigetfile({'*.tif;*.tiff;*.jpg;*.jpeg;*.png'},'Select image.');
            if isequal(fn,0)
                return;
            end
            app.keepFocus;
            app.sourceFilePath = [pn fn];
            app.sourceFileName = fn;
            app.sourceImage = imread(app.sourceFilePath);
            app.ImagefileButton.Text = app.sourceFileName;
            app.ImageView.ImageSource = app.sourceImage;
            % cc = images.color.ColorConverter(); % a reminder that I can go deeper into the api - for research

            % attempt to extract the color profile of the image, if
            % available. If not, then color the image profile button and adust the prompt.
            try
                app.sourceProfile = iccread(app.sourceFilePath);
                app.sourceProfilePath = null;
                app.SourceProfileButton.Text = app.sourceProfile.Description.String;
                app.SourceProfileButton.BackgroundColor = app.defaultBackground;
            catch
                % no profile.
                app.SourceProfileButton.BackgroundColor = app.alertBackground;
                app.SourceProfileButton.Text = "Select a profile for the image.";
            end
            focus(app.SourceProfileButton);
        end

        % Button pushed function: SourceProfileButton
        function SourceProfileButtonPushed(app, event)
            [fn, pn] = uigetfile({'*.icc'; '*.icm'},'Select first profile (wireframe).', iccroot);
            app.keepFocus;
            if isequal(fn,0)
                return;
            end
            app.sourceProfilePath = [pn fn];
            try
                app.sourceProfile = iccread(app.sourceProfilePath);
                app.SourceProfileButton.Text = app.sourceProfile.Description.String;
                app.SourceProfileButton.BackgroundColor = app.defaultBackground;
            catch
            end
        end

        % Button pushed function: DestProfileButton
        function DestProfileButtonPushed(app, event)
            [fn, pn] = uigetfile({'*.icc'; '*.icm'},'Select first profile (wireframe).', iccroot);
            app.keepFocus;
            if isequal(fn,0)
                return;
            end
            app.destinationProfilePath = [pn fn];
            try
                app.destinationProfile = iccread(app.destinationProfilePath);
                app.DestProfileButton.Text = app.destinationProfile.Description.String;
            catch
            end
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            % move the input image to LAB space so the splatter makes sense
            % with the axes
            app.RenderButton.BackgroundColor = "#f5f5f5";
            app.UIAxes.reset;
            % Create a 3D scatter plot using my function stripped from
            % colorcloud function (so I could push rgb profile image in)
            colorPoints = myColorCloud(app.sourceImage, app.sourceProfile,  app.UIAxes);
            [x, y, ~] = size(app.sourceImage);
            app.ImageColorsCountLabel.Text = thousandSeparatorFormat(x*y);
            app.ImageUniqueColorsCountLabel.Text = thousandSeparatorFormat(colorPoints);
            hold(app.UIAxes, "on");
            app.UIAxes.Color = [0.8,0.8,0.8];
            xlabel(app.UIAxes, "a*");
            ylabel(app.UIAxes, "b*");
            zlabel(app.UIAxes, "L*");
            rotate3d(app.UIAxes, 'on');
            axis(app.UIAxes, "vis3d");
            grid(app.UIAxes, "on");
            % app.UIAxes.YLim = [0 100];

            if isicc(app.destinationProfile)
                try
                    C1 = makecform("clut", app.destinationProfile,"AToB0");
                catch
                end
                try
                    C1 = makecform("mattrc", app.destinationProfile, Direction="forward");
                catch
                end
                target1 = applycform(app.referenceImage,C1);
                clear C1;
                switch app.destinationProfile.Header.ConnectionSpace
                    case "XYZ"
                        target1 = xyz2lab(target1);
                    case "Lab"
                    otherwise
                        disp(["unknown pcs ... " pcs]);
                end
                surf(app.UIAxes, target1(:,:,2), target1(:,:,3), target1(:,:,1), ...
                    app.referenceImage, FaceColor='none', EdgeColor='flat', EdgeAlpha=app.destProfileIntensity);
                % hold(app.UIAxes, "on");
            end
            app.keepFocus;
        end

        % Value changed function: wireframetransparencySlider
        function wireframetransparencySliderValueChanged(app, event)
            app.destProfileIntensity = app.wireframetransparencySlider.Value;
            app.RenderButton.BackgroundColor = "Yellow";
        end

        % Button pushed function: viewOrthogonalButton
        function viewOrthogonalButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, 3);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: viewBottomUpButton
        function viewBottomUpButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, [0,-90]);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: viewTopDownButton
        function viewTopDownButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, 2);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: SourceProfileInfoButton
        function sourceProfileInfoButtonPushed(app, event)
            ProfileInformation(app.sourceProfile, app.sourceProfilePath);
        end

        % Button pushed function: astarLeftButton
        function astarLeftButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, [0,0]);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: bstarLeftButton
        function bstarLeftButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, [90 ,0]);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: DestinationProfileInfoButton
        function DestinationProfileInfoButtonPushed(app, event)
            ProfileInformation(app.destinationProfile, app.destinationProfilePath);
        end

        % Button pushed function: aStarRightButton
        function aStarRightButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, [180,0]);
            rotate3d(app.UIAxes, 'on');
        end

        % Button pushed function: bStarRightButton
        function bStarRightButtonPushed(app, event)
            rotate3d(app.UIAxes, 'off');
            view(app.UIAxes, [-90 ,0]);
            rotate3d(app.UIAxes, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'PointCloudIcon.png';
            app.UIFigure.Tag = 'ColorToolboxtag';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.destinationprofileopacityLabel = uilabel(app.GridLayout2);
            app.destinationprofileopacityLabel.Text = 'destination profile opacity';
            app.wireframetransparencySlider = uislider(app.GridLayout2);
            app.wireframetransparencySlider.Limits = [0 1];
            app.wireframetransparencySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.wireframetransparencySlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.wireframetransparencySlider.ValueChangedFcn = createCallbackFcn(app, @wireframetransparencySliderValueChanged, true);
            app.wireframetransparencySlider.MinorTicks = [];
            app.wireframetransparencySlider.Value = 0.5;
            app.viewOrthogonalButton = uibutton(app.GridLayout2, 'push');
            app.viewOrthogonalButton.ButtonPushedFcn = createCallbackFcn(app, @viewOrthogonalButtonPushed, true);
            app.viewOrthogonalButton.Icon = 'orthographic-arrow.png';
            app.viewOrthogonalButton.Tooltip = {'Orthogonal view'};
            app.viewOrthogonalButton.Text = '';
            app.viewTopDownButton = uibutton(app.GridLayout2, 'push');
            app.viewTopDownButton.ButtonPushedFcn = createCallbackFcn(app, @viewTopDownButtonPushed, true);
            app.viewTopDownButton.Icon = 'down-arrow.png';
            app.viewTopDownButton.Tooltip = {'top-down view'};
            app.viewTopDownButton.Text = '';
            app.viewBottomUpButton = uibutton(app.GridLayout2, 'push');
            app.viewBottomUpButton.ButtonPushedFcn = createCallbackFcn(app, @viewBottomUpButtonPushed, true);
            app.viewBottomUpButton.Icon = 'up-arrow.png';
            app.viewBottomUpButton.Tooltip = {'bottom-up view'};
            app.viewBottomUpButton.Text = '';
            app.bstarLeftButton = uibutton(app.GridLayout2, 'push');
            app.bstarLeftButton.ButtonPushedFcn = createCallbackFcn(app, @bstarLeftButtonPushed, true);
            app.bstarLeftButton.Icon = 'arrow-left-icon.png';
            app.bstarLeftButton.Text = '-b*';
            app.astarLeftButton = uibutton(app.GridLayout2, 'push');
            app.astarLeftButton.ButtonPushedFcn = createCallbackFcn(app, @astarLeftButtonPushed, true);
            app.astarLeftButton.Icon = 'arrow-left-icon.png';
            app.astarLeftButton.Text = '-a*';
            app.bStarRightButton = uibutton(app.GridLayout2, 'push');
            app.bStarRightButton.ButtonPushedFcn = createCallbackFcn(app, @bStarRightButtonPushed, true);
            app.bStarRightButton.Icon = 'arrow-right-icon.png';
            app.bStarRightButton.Text = '-b*';
            app.aStarRightButton = uibutton(app.GridLayout2, 'push');
            app.aStarRightButton.ButtonPushedFcn = createCallbackFcn(app, @aStarRightButtonPushed, true);
            app.aStarRightButton.Icon = 'arrow-right-icon.png';
            app.aStarRightButton.Text = '-a*';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ImagepixelsLabel = uilabel(app.GridLayout3);
            app.ImagepixelsLabel.Text = 'Image pixels';
            app.UniquecolorsLabel = uilabel(app.GridLayout3);
            app.UniquecolorsLabel.Text = 'Unique colors';
            app.ImageColorsCountLabel = uilabel(app.GridLayout3);
            app.ImageColorsCountLabel.Text = '';
            app.ImageUniqueColorsCountLabel = uilabel(app.GridLayout3);
            app.ImageUniqueColorsCountLabel.Text = '';
            app.ImageView = uiimage(app.GridLayout2);
            app.ImagefileButton = uibutton(app.GridLayout, 'push');
            app.ImagefileButton.ButtonPushedFcn = createCallbackFcn(app, @ImagefileButtonPushed, true);
            app.ImagefileButton.Text = 'Image file';
            app.SourceProfileButton = uibutton(app.GridLayout, 'push');
            app.SourceProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SourceProfileButtonPushed, true);
            app.SourceProfileButton.Text = 'Source Profile';
            app.DestProfileButton = uibutton(app.GridLayout, 'push');
            app.DestProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DestProfileButtonPushed, true);
            app.DestProfileButton.Text = 'Destination Profile';
            app.RenderButton = uibutton(app.GridLayout, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'render';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ImageLabel = uilabel(app.GridLayout);
            app.ImageLabel.Text = 'Image';
            app.ImageProfileLabel = uilabel(app.GridLayout);
            app.ImageProfileLabel.Text = 'Image Profile';
            app.DestProfileLabel = uilabel(app.GridLayout);
            app.DestProfileLabel.Text = 'Dest. Profile';
            app.SourceProfileInfoButton = uibutton(app.GridLayout, 'push');
            app.SourceProfileInfoButton.ButtonPushedFcn = createCallbackFcn(app, @sourceProfileInfoButtonPushed, true);
            app.SourceProfileInfoButton.Icon = 'info-icon-23815.png';
            app.SourceProfileInfoButton.Text = '';
            app.DestinationProfileInfoButton = uibutton(app.GridLayout, 'push');
            app.DestinationProfileInfoButton.ButtonPushedFcn = createCallbackFcn(app, @DestinationProfileInfoButtonPushed, true);
            app.DestinationProfileInfoButton.Icon = 'info-icon-23815.png';
            app.DestinationProfileInfoButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImagePointCloud

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end