classdef ProfileInformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProfileInformationUIFigure  matlab.ui.Figure
        ExitBtn                     matlab.ui.control.Button
        ProfileBtn                  matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        Overview                    matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        copyrightField              matlab.ui.control.Label
        copyrightLabel              matlab.ui.control.Label
        deviceTypeField             matlab.ui.control.Label
        devicetypeLabel             matlab.ui.control.Label
        whitePointGrid              matlab.ui.container.GridLayout
        whitePointField_b           matlab.ui.control.Label
        whitePointField_a           matlab.ui.control.Label
        whitePointField_L           matlab.ui.control.Label
        bLabel_3                    matlab.ui.control.Label
        aLabel_3                    matlab.ui.control.Label
        LLabel_3                    matlab.ui.control.Label
        blackPointGrid              matlab.ui.container.GridLayout
        blackpoint_b_field          matlab.ui.control.Label
        blackpoint_a_field          matlab.ui.control.Label
        blackpoint_L_field          matlab.ui.control.Label
        bLabel_2                    matlab.ui.control.Label
        aLabel_2                    matlab.ui.control.Label
        LLabel_2                    matlab.ui.control.Label
        profileLocationField        matlab.ui.control.Label
        LocationLabel               matlab.ui.control.Label
        blackpointLabel             matlab.ui.control.Label
        whitepointLabel             matlab.ui.control.Label
        profileNameField            matlab.ui.control.Label
        profileNameLabel            matlab.ui.control.Label
        Header                      matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        IndependentChbx             matlab.ui.control.CheckBox
        IndepdentLabel              matlab.ui.control.Label
        EmbeddedChbx                matlab.ui.control.CheckBox
        EmbeddedLabel               matlab.ui.control.Label
        CreatorField                matlab.ui.control.Label
        IlluminantField             matlab.ui.control.Label
        IntentField                 matlab.ui.control.Label
        AttributesField             matlab.ui.control.Label
        DeviceModelField            matlab.ui.control.Label
        DeviceManufacturerField     matlab.ui.control.Label
        GridLayout3                 matlab.ui.container.GridLayout
        FlagsField                  matlab.ui.control.Label
        PrimaryPlatformField        matlab.ui.control.Label
        SignatureField              matlab.ui.control.Label
        DateField                   matlab.ui.control.Label
        ConnectionSpaceField        matlab.ui.control.Label
        ColorSpaceField             matlab.ui.control.Label
        DeviceClassField            matlab.ui.control.Label
        VersionField                matlab.ui.control.Label
        CMMTypeField                matlab.ui.control.Label
        sizeField                   matlab.ui.control.Label
        CreatorLabel                matlab.ui.control.Label
        IlluminantLabel             matlab.ui.control.Label
        IntentLabel                 matlab.ui.control.Label
        AttributeLabel              matlab.ui.control.Label
        ModelLabel                  matlab.ui.control.Label
        ManufacturerLabel           matlab.ui.control.Label
        FlagsLabel                  matlab.ui.control.Label
        PlatformLabel               matlab.ui.control.Label
        MagicLabel                  matlab.ui.control.Label
        DateLabel                   matlab.ui.control.Label
        PCSLabel                    matlab.ui.control.Label
        ColorSpaceLabel             matlab.ui.control.Label
        DeviceClassLabel            matlab.ui.control.Label
        VersionLabel                matlab.ui.control.Label
        CMMTypeLabel                matlab.ui.control.Label
        SizeLabel                   matlab.ui.control.Label
        TagTable                    matlab.ui.container.Tab
        requiredField               matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        TagTableField               matlab.ui.control.Table
        Curves                      matlab.ui.container.Tab
        StatisticsTab               matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        SizeBytesField              matlab.ui.control.Label
        SizebytesLabel              matlab.ui.control.Label
        GamutVolumeField            matlab.ui.control.Label
        GamutVolumeLabel            matlab.ui.control.Label
        ChromaticityTab             matlab.ui.container.Tab
        ChromaticityUIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        profilePath    % The full path including name of the profile
        profileLocation % The directory of the profile
        profileName    % the display name of the profile
        profileStruct    % struct holding the profile information from iccread


        profile_file_ID % handle to the opened profile file.
        profile_file    % memory-mapped read of the profile file. Used to access the tag table
        memProfile      % memory-mapped image of the profile file
    end

    methods (Access = private)

        function process_tag_table(app)
            %PROCESS_TAG_TABLE process the icc file's tag table
            % returns a table suitable for a UI table.
            % stores the decoded tag table data into a table for
            % use in the UI
            % fields are: Tag, offset and length per row.
            [nr,~]=size(app.profileStruct.TagTable);
            for i = 1:nr
                offset = app.profileStruct.TagTable{i,2} + 1;
                len = app.profileStruct.TagTable{i,3};
                tsig = app.memProfile.Data(offset:offset+3)';
                sig = char(tsig);
                tdesc = app.memProfile.Data(offset+3:offset+len-3)';
                desc = char(tdesc);
                trow = {num2str(i) app.profileStruct.TagTable{i, 1}, sig, desc};
                app.TagTableField.Data = [app.TagTableField.Data; trow];
            end
        end

        function processProfile(app)
            % Profile versions store information differently.
            % however, I still check the structure, rather than infer from
            % the version.
            % the ICC spec. still allows for some flex on structure.

            % read the file in so we can access the contents of the tag table
            app.profilePath
            app.memProfile = memmapfile(app.profilePath);
            process_tag_table(app);
            app.SizeBytesField.Text = num2str(size(app.memProfile,1));

            app.profileNameField.Text = app.profileStruct.Description.String;
            app.ProfileInformationUIFigure.Name = "Profile Information: " + app.profileStruct.Description.String;
            app.ProfileBtn.Text = app.profileStruct.Description.String;
            app.profileLocationField.Text = app.profilePath;

            % copyright might be either a field or a string in a struct
            if isstruct(app.profileStruct.Copyright)
                app.copyrightField.Text = app.profileStruct.Copyright.String;
            else
                app.copyrightField.Text = app.profileStruct.Copyright;
            end

            app.whitePointField_L.Text = num2str(app.profileStruct.MediaWhitePoint(1));
            app.whitePointField_a.Text = num2str(app.profileStruct.MediaWhitePoint(2));
            app.whitePointField_b.Text = num2str(app.profileStruct.MediaWhitePoint(3));

            % blackpoints are not guaranteed to be present so check and
            % markup label if not present.
            if isfield(app.profileStruct, 'MediaBlackPoint')
                app.blackpoint_L_field.Text = num2str(app.profileStruct.MediaBlackPoint(1));
                app.blackpoint_a_field.Text = num2str(app.profileStruct.MediaBlackPoint(2));
                app.blackpoint_b_field.Text = num2str(app.profileStruct.MediaBlackPoint(3));
            else
                app.blackpointLabel.Text = "black point (not present in profile)";
            end

            % Header fields
            app.sizeField.Text = num2str(app.profileStruct.Header.Size);
            app.CMMTypeField.Text = app.profileStruct.Header.CMMType;
            app.VersionField.Text = app.profileStruct.Header.Version;
            app.DeviceClassField.Text = app.profileStruct.Header.DeviceClass;
            app.ColorSpaceField.Text = app.profileStruct.Header.ColorSpace;
            app.ConnectionSpaceField.Text = app.profileStruct.Header.ConnectionSpace;
            app.DateField.Text = app.profileStruct.Header.CreationDate;
            app.SignatureField.Text = app.profileStruct.Header.Signature;
            app.PrimaryPlatformField.Text = app.profileStruct.Header.PrimaryPlatform;
            % app.FlagsField.Text = app.profileStruct.Header.Flags; % hold off
            % for now
            if app.profileStruct.Header.IsEmbedded
                app.EmbeddedChbx.Value = 1;
            else
                app.EmbeddedChbx.Value = 0;
            end
            if app.profileStruct.Header.IsIndependent
                app.IndependentChbx.Value = 1;
            else
                app.IndependentChbx.Value = 0;
            end
            app.DeviceManufacturerField.Text = app.profileStruct.Header.DeviceManufacturer;
            app.DeviceModelField.Text = app.profileStruct.Header.DeviceModel;
            app.AttributesField.Text = "";
            if app.profileStruct.Header.IsTransparency
                app.AttributesField.Text = strcat(app.AttributesField.Text, "transparent,");
            else
                app.AttributesField.Text = strcat(app.AttributesField.Text, "reflective,");
            end
            if app.profileStruct.Header.IsMatte
                app.AttributesField.Text = strcat(app.AttributesField.Text,"matte,");
            else
                app.AttributesField.Text = strcat(app.AttributesField.Text, "glossy,");

            end
            if app.profileStruct.Header.IsNegative
                app.AttributesField.Text = strcat(app.AttributesField.Text,"negative,");
            else
                app.AttributesField.Text = strcat(app.AttributesField.Text, "positive,");
            end
            if app.profileStruct.Header.IsBlackandWhite
                app.AttributesField.Text = strcat(app.AttributesField.Text,"B&W");
            else
                app.AttributesField.Text = strcat(app.AttributesField.Text, "color");

            end
            app.IntentField.Text = app.profileStruct.Header.RenderingIntent;
            app.IlluminantField.Text = num2str(app.profileStruct.Header.Illuminant(1));
            app.IlluminantField.Text = strcat(app.IlluminantField.Text, ", ", num2str(app.profileStruct.Header.Illuminant(2)));
            app.IlluminantField.Text = strcat(app.IlluminantField.Text, ", ", num2str(app.profileStruct.Header.Illuminant(3)));
            app.CreatorField.Text = app.profileStruct.Header.Creator;

            % tag table. This is a bit more complex, because iccread only
            % provides the indexing table. We need to access the pointed-to
            % data.
            app.process_tag_table();

            % build the statistics
            app.GamutVolumeField.Text = "0";

            % produce a standard chromaticity diagram.
            % plotChromaticity(app.profileStruct.Chromaticity);

            %          Header: [1x1 struct]
            %          TagTable: {17x3 cell}
            %         Copyright: 'Copyright (c) 1999 Hewlett-Packard Company'
            %       Description: [1x1 struct]
            %   MediaWhitePoint: [0.9505 1 1.0891]
            %   MediaBlackPoint: [0 0 0]
            %     DeviceMfgDesc: [1x1 struct]
            %   DeviceModelDesc: [1x1 struct]
            %   ViewingCondDesc: [1x1 struct]
            % ViewingConditions: [1x1 struct]
            %         Luminance: [76.0365 80 87.1246]
            %       Measurement: [1x1 struct]
            %        Technology: 'Cathode Ray Tube Display'
            %            MatTRC: [1x1 struct]
            %       PrivateTags: {}
            %          Filename: 'sRGB.icm'

            % Struct
            % app.profileStruct.Header
            % Header fields
            % app.profileStruct.Header.Size
            % app.profileStruct.Header.CMMType
            % app.profileStruct.Header.Version
            % app.profileStruct.Header.DeviceClass
            % app.profileStruct.Header.ColorSpace
            % app.profileStruct.Header.ConnectionSpace
            % app.profileStruct.Header.CreationDate
            % app.profileStruct.Header.Signature
            % app.profileStruct.Header.PrimaryPlatform
            % app.profileStruct.Header.Flags
            % app.profileStruct.Header.IsEmbedded
            % app.profileStruct.Header.IsIndependent
            % app.profileStruct.Header.DeviceManufacturer
            % app.profileStruct.Header.DeviceModel
            % app.profileStruct.Header.Attributes
            % app.profileStruct.Header.IsTransparency
            % app.profileStruct.Header.IsMatte
            % app.profileStruct.Header.IsNegative
            % app.profileStruct.Header.IsBlackandWhite
            % app.profileStruct.Header.RenderingIntent
            % app.profileStruct.Header.Illuminant
            % app.profileStruct.Header.Creator
            % app.profileStruct.TagTable (mutliple table entries, iteration)
            % app.profileStruct.Description
            % app.profileStruct.Description.String
            % app.profileStruct.Copyright
            % app.profileStruct.DeviceMfgDesc
            % app.profileStruct.DeviceMfgDesc.String
            % app.profileStruct.DeviceModelDesc
            % app.profileStruct.DeviceModelDesc
            % app.profileStruct.MediaWhitePoint
            % app.profileStruct.Chromaticity
            % app.profileStruct.Chromaticity.ColorantCode
            % app.profileStruct.Chromaticity.ColorantType
            % app.profileStruct.Chromaticity.xy (4x4)
            % app.profileStruct.Luminance
            % app.profileStruct.MediaBlackPoint
            % app.profileStruct.CharTarget (big mess o stuff)
            % app.profileStruct.MatTRC
            %    app.profileStruct.MatTRC.RedColorant
            %    app.profileStruct.MatTRC.RedTRC
            %    app.profileStruct.MatTRC.GreenColorant
            %    app.profileStruct.MatTRC.GreenTRC
            %    app.profileStruct.MatTRC.BlueColorant
            %    app.profileStruct.MatTRC.BlueTRC
            % app.profileStruct.PrivateTags
            % app.profileStruct.Filename

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputProfile, inputProfilePath)
            if nargin ~= 3
                return;
            end

            try
                if isicc(inputProfile)
                    app.profileStruct = inputProfile;
                end
            catch
            end
            try
                mustbeFile(inputProfilePath)
                app.profilePath = inputProfilePath;
            catch
                app.profilePath = "embedded";
            end
        end

        % Button pushed function: ExitBtn
        function ExitBtnButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: ProfileBtn
        function ProfileBtnButtonPushed(app, event)
            %Note that ICC profiles are big-endian. This machine is
            %little-endian and any number > 8 bit needs to be converted.
            % [file, path] = uigetfile({'*.icc;*.icm;'}, 'Select input color profile', iccroot);
            [fn, pn] = uigetfile({'*.icc;*.icm'},'Select profile', iccroot);
            figure(app.ProfileInformationUIFigure);
            if isequal(fn,0)
                app.ResetProfile1Button();
            else
                app.profilePath = [pn fn];
                app.profileStruct = iccread(app.profilePath);
                app.processProfile();
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.TabGroup.SelectedTab.Title == app.ChromaticityTab.Title
                xyz_primaries = rgb2xyz([1 0 0; 0 1 0; 0 0 1]);
                xyzMag = sum(xyz_primaries,2);
                x_primary = xyz_primaries(:,1)./xyzMag;
                y_primary = xyz_primaries(:,2)./xyzMag;
                wp = whitepoint('D65');
                wpMag = sum(wp,2);
                x_whitepoint = wp(:,1)./wpMag;
                y_whitepoint = wp(:,2)./wpMag;
                %figure('Visible', 'off');
                plotChromaticity("Parent", app.ChromaticityUIAxes);
                hold(app.ChromaticityUIAxes, 'on');
                scatter(app.ChromaticityUIAxes, x_whitepoint,y_whitepoint,36,'black')
                scatter(app.ChromaticityUIAxes, x_primary,y_primary,36,'black')
                plot(app.ChromaticityUIAxes, [x_primary; x_primary],[y_primary; y_primary],'k')
                hold(app.ChromaticityUIAxes, 'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProfileInformationUIFigure = uifigure('Visible', 'off');
            app.ProfileInformationUIFigure.Name = 'ProfileInformation';
            app.ProfileInformationUIFigure.Tag = 'ColorToolboxTag';
            app.TabGroup = uitabgroup(app.ProfileInformationUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Overview = uitab(app.TabGroup);
            app.Overview.Title = 'Overview';
            app.GridLayout = uigridlayout(app.Overview);
            app.profileNameLabel = uilabel(app.GridLayout);
            app.profileNameLabel.Text = 'profile name';
            app.profileNameField = uilabel(app.GridLayout);
            app.profileNameField.Text = '';
            app.whitepointLabel = uilabel(app.GridLayout);
            app.whitepointLabel.Text = 'white point:';
            app.blackpointLabel = uilabel(app.GridLayout);
            app.blackpointLabel.Text = 'black point:';
            app.LocationLabel = uilabel(app.GridLayout);
            app.LocationLabel.Text = 'Location';
            app.profileLocationField = uilabel(app.GridLayout);
            app.profileLocationField.Text = '';
            app.blackPointGrid = uigridlayout(app.GridLayout);
            app.LLabel_2 = uilabel(app.blackPointGrid);
            app.LLabel_2.Text = 'L';
            app.aLabel_2 = uilabel(app.blackPointGrid);
            app.aLabel_2.Text = 'a*';
            app.bLabel_2 = uilabel(app.blackPointGrid);
            app.bLabel_2.Text = 'b*';
            app.blackpoint_L_field = uilabel(app.blackPointGrid);
            app.blackpoint_L_field.Text = '';
            app.blackpoint_a_field = uilabel(app.blackPointGrid);
            app.blackpoint_a_field.Text = '';
            app.blackpoint_b_field = uilabel(app.blackPointGrid);
            app.blackpoint_b_field.Text = '';
            app.whitePointGrid = uigridlayout(app.GridLayout);
            app.LLabel_3 = uilabel(app.whitePointGrid);
            app.LLabel_3.Text = 'L';
            app.aLabel_3 = uilabel(app.whitePointGrid);
            app.aLabel_3.Text = 'a*';
            app.bLabel_3 = uilabel(app.whitePointGrid);
            app.bLabel_3.Text = 'b*';
            app.whitePointField_L = uilabel(app.whitePointGrid);
            app.whitePointField_L.Text = '';
            app.whitePointField_a = uilabel(app.whitePointGrid);
            app.whitePointField_a.Text = '';
            app.whitePointField_b = uilabel(app.whitePointGrid);
            app.whitePointField_b.Text = '';
            app.devicetypeLabel = uilabel(app.GridLayout);
            app.devicetypeLabel.Text = 'device type:';
            app.deviceTypeField = uilabel(app.GridLayout);
            app.deviceTypeField.Text = '';
            app.copyrightLabel = uilabel(app.GridLayout);
            app.copyrightLabel.Text = 'copyright';
            app.copyrightField = uilabel(app.GridLayout);
            app.copyrightField.Text = '';
            app.Header = uitab(app.TabGroup);
            app.Header.Title = 'Header';
            app.GridLayout2 = uigridlayout(app.Header);
            app.SizeLabel = uilabel(app.GridLayout2);
            app.SizeLabel.Text = 'Size:';
            app.CMMTypeLabel = uilabel(app.GridLayout2);
            app.CMMTypeLabel.Text = 'CMM Type:';
            app.VersionLabel = uilabel(app.GridLayout2);
            app.VersionLabel.Text = 'Version:';
            app.DeviceClassLabel = uilabel(app.GridLayout2);
            app.DeviceClassLabel.Text = 'Device Class:';
            app.ColorSpaceLabel = uilabel(app.GridLayout2);
            app.ColorSpaceLabel.Text = 'Color Space:';
            app.PCSLabel = uilabel(app.GridLayout2);
            app.PCSLabel.Text = 'PCS:';
            app.DateLabel = uilabel(app.GridLayout2);
            app.DateLabel.Text = 'Date:';
            app.MagicLabel = uilabel(app.GridLayout2);
            app.MagicLabel.Text = 'Magic:';
            app.PlatformLabel = uilabel(app.GridLayout2);
            app.PlatformLabel.Text = 'Platform:';
            app.FlagsLabel = uilabel(app.GridLayout2);
            app.FlagsLabel.Text = 'Flags:';
            app.ManufacturerLabel = uilabel(app.GridLayout2);
            app.ManufacturerLabel.Text = 'Manufacturer';
            app.ModelLabel = uilabel(app.GridLayout2);
            app.ModelLabel.Text = 'Model:';
            app.AttributeLabel = uilabel(app.GridLayout2);
            app.AttributeLabel.Text = 'Attribute';
            app.IntentLabel = uilabel(app.GridLayout2);
            app.IntentLabel.Text = 'Intent';
            app.IlluminantLabel = uilabel(app.GridLayout2);
            app.IlluminantLabel.Text = 'Illuminant';
            app.CreatorLabel = uilabel(app.GridLayout2);
            app.CreatorLabel.Text = 'Creator';
            app.sizeField = uilabel(app.GridLayout2);
            app.sizeField.Text = '';
            app.CMMTypeField = uilabel(app.GridLayout2);
            app.CMMTypeField.Text = '';
            app.VersionField = uilabel(app.GridLayout2);
            app.VersionField.Text = '';
            app.DeviceClassField = uilabel(app.GridLayout2);
            app.DeviceClassField.Text = '';
            app.ColorSpaceField = uilabel(app.GridLayout2);
            app.ColorSpaceField.Text = '';
            app.ConnectionSpaceField = uilabel(app.GridLayout2);
            app.ConnectionSpaceField.Text = '';
            app.DateField = uilabel(app.GridLayout2);
            app.DateField.Text = '';
            app.SignatureField = uilabel(app.GridLayout2);
            app.SignatureField.Text = '';
            app.PrimaryPlatformField = uilabel(app.GridLayout2);
            app.PrimaryPlatformField.Text = '';
            app.FlagsField = uilabel(app.GridLayout2);
            app.FlagsField.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.DeviceManufacturerField = uilabel(app.GridLayout2);
            app.DeviceManufacturerField.Text = '';
            app.DeviceModelField = uilabel(app.GridLayout2);
            app.DeviceModelField.Text = '';
            app.AttributesField = uilabel(app.GridLayout2);
            app.AttributesField.Text = '';
            app.IntentField = uilabel(app.GridLayout2);
            app.IntentField.Text = '';
            app.IlluminantField = uilabel(app.GridLayout2);
            app.IlluminantField.Text = '';
            app.CreatorField = uilabel(app.GridLayout2);
            app.CreatorField.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.EmbeddedLabel = uilabel(app.GridLayout5);
            app.EmbeddedLabel.Text = 'Embedded';
            app.EmbeddedChbx = uicheckbox(app.GridLayout5);
            app.EmbeddedChbx.Enable = 'off';
            app.EmbeddedChbx.Text = '';
            app.IndepdentLabel = uilabel(app.GridLayout5);
            app.IndepdentLabel.Text = 'Indepdent';
            app.IndependentChbx = uicheckbox(app.GridLayout5);
            app.IndependentChbx.Enable = 'off';
            app.IndependentChbx.Text = '';
            app.TagTable = uitab(app.TabGroup);
            app.TagTable.Title = 'Tag Table';
            app.TagTableField = uitable(app.TagTable);
            app.TagTableField.ColumnName = {'#'; 'tag'; 'type'; 'desc.'; 'offset'; 'size'};
            app.TagTableField.RowName = {};
            app.TextArea = uitextarea(app.TagTable);
            app.TextArea.Editable = 'off';
            app.requiredField = uilabel(app.TagTable);
            app.requiredField.Text = 'required';
            app.Curves = uitab(app.TabGroup);
            app.Curves.Title = 'Curves';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.GridLayout4 = uigridlayout(app.StatisticsTab);
            app.GamutVolumeLabel = uilabel(app.GridLayout4);
            app.GamutVolumeLabel.Text = 'Gamut Volume';
            app.GamutVolumeField = uilabel(app.GridLayout4);
            app.GamutVolumeField.Text = '';
            app.SizebytesLabel = uilabel(app.GridLayout4);
            app.SizebytesLabel.Text = 'Size (bytes)';
            app.SizeBytesField = uilabel(app.GridLayout4);
            app.SizeBytesField.Text = '';
            app.ChromaticityTab = uitab(app.TabGroup);
            app.ChromaticityTab.Title = 'Chromaticity';
            app.ChromaticityUIAxes = uiaxes(app.ChromaticityTab);
            app.ChromaticityUIAxes.XTick = [];
            app.ChromaticityUIAxes.YTick = [];
            app.ProfileBtn = uibutton(app.ProfileInformationUIFigure, 'push');
            app.ProfileBtn.ButtonPushedFcn = createCallbackFcn(app, @ProfileBtnButtonPushed, true);
            app.ProfileBtn.Text = 'Profile';
            app.ExitBtn = uibutton(app.ProfileInformationUIFigure, 'push');
            app.ExitBtn.ButtonPushedFcn = createCallbackFcn(app, @ExitBtnButtonPushed, true);
            app.ExitBtn.Text = 'Exit';
            % Show the figure after all components are created
            app.ProfileInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProfileInformation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProfileInformationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProfileInformationUIFigure)
        end
    end
end